classdef ThreeBandFilterApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        LowKnobLabel          matlab.ui.control.Label
        LowKnob               matlab.ui.control.Knob
        LoadAudioButton       matlab.ui.control.Button
        PauseAudioButton      matlab.ui.control.Button
        PlayAudioButton       matlab.ui.control.Button
        StopAudioButton       matlab.ui.control.Button
        AudioName             matlab.ui.control.Label
        MidKnobLabel          matlab.ui.control.Label
        MidKnob               matlab.ui.control.Knob
        HighKnobLabel         matlab.ui.control.Label
        HighKnob              matlab.ui.control.Knob
        LowValue              matlab.ui.control.NumericEditField
        MidValue              matlab.ui.control.NumericEditField
        HighValue             matlab.ui.control.NumericEditField
        ThreeBandFilterLabel  matlab.ui.control.Label
        Playing________Label  matlab.ui.control.Label
        LoudnessSwitchLabel   matlab.ui.control.Label
        LoudnessSwitch        matlab.ui.control.RockerSwitch
    end

    
      properties (Access = private)
        Audio_input % Description
        SamplingFreq % Description
        Audio_loadfile_name % Description
        Audio_loadfile_ext % Description
        Audio_loadpath % Description
        Audio_loadfile % Description
        Audio_savefile % Description
        Audio_savepath % Description
        Audio_write % Description
        Audio_play % Description
        Gain_output_flag % Description
        Audio_playback_object % Description
        Audio_processed % Description
        Audio_weighted
        h_low % filter response for low shelf
        h_mid % filter response for mid peak
        h_high % filter response for high shelf
        h_omega % filter response angular frequency values
        h_prod % product of all filter responses
        Audio_pause_time % audio sample packet number where playback paused
    end
    
    methods (Access = private)
        
        function [b,a] = ShelvingFilter(app,type,G)
            
            if abs(G) <= 0.01
                b = 1;
                a = 1;
            else
                if (strcmp(type,'Low'))
                    K = tan(pi*200/app.SamplingFreq);
                else
                    K = tan(pi*5000/app.SamplingFreq);
                end
                V0 = 10^(G/20);
                if (G>=0 && strcmp(type,'Low'))
                % Low Boost
                    b0 = (1+sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2)*K+K^2);
                    b1 = 2*(V0*K^2-1)/(1+sqrt(2)*K+K^2);
                    b2 = (1-sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2)*K+K^2);
                    a1 = 2*(K^2-1)/(1+sqrt(2)*K+K^2);
                    a2 = (1-sqrt(2)*K+K^2)/(1+sqrt(2)*K+K^2);
    
                elseif (G<0 && strcmp(type,'Low'))
                % Low Cut
                    b0 = V0*(1+sqrt(2)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
                    b1 = 2*V0*(K^2-1)/(V0+sqrt(2*V0)*K+K^2);
                    b2 = V0*(1-sqrt(2)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
                    a1 = 2*(K^2-V0)/(V0+sqrt(2*V0)*K+K^2);
                    a2 = (V0-sqrt(2*V0)*K+K^2)/(V0+sqrt(2*V0)*K+K^2);
   
                elseif (G>=0 && strcmp(type,'High'))
                % High Boost
                    b0 = (V0+sqrt(2*V0)*K+K^2)/(1+sqrt(2)*K+K^2);
                    b1 = 2*(K^2-V0)/(1+sqrt(2)*K+K^2);
                    b2 = (V0-sqrt(2*V0)*K+K^2)/(1+sqrt(2)*K+K^2);
                    a1 = 2*(K^2-1)/(1+sqrt(2)*K+K^2);
                    a2 = (1-sqrt(2)*K+K^2)/(1+sqrt(2)*K+K^2);
    
                else
                %High Cut
                    b0 = V0*(1+sqrt(2)*K+K^2)/(1+sqrt(2*V0)*K+V0*K^2);
                    b1 = 2*V0*(K^2-1)/(1+sqrt(2*V0)*K+V0*K^2);
                    b2 = V0*(1-sqrt(2)*K+K^2)/(1+sqrt(2*V0)*K+V0*K^2);
                    a1 = 2*(V0*K^2-1)/(1+sqrt(2*V0)*K+V0*K^2);
                    a2 = (1-sqrt(2*V0)*K+V0*K^2)/(1+sqrt(2*V0)*K+V0*K^2);

                end
                a = [1, a1, a2];
                b = [b0, b1, b2];
            end
            
        end
        
        function [b,a] = PeakingFilter(app,G)
            
            if abs(G) <= 0.01
                b = 1;
                a = 1;
            else
                K = tan(pi*1000/app.SamplingFreq);
                V0 = 10^(G/20);
                Q=1;
                if (G>=0)
                % Boost
                    b0 = (1+V0*K/Q+K^2)/(1+K/Q+K^2);
                    b1 = 2*(K^2-1)/(1+K/Q+K^2);
                    b2 = (1-V0*K/Q+K^2)/(1+K/Q+K^2);
                    a1 = 2*(K^2-1)/(1+K/Q+K^2);
                    a2 = (1-K/Q+K^2)/(1+K/Q+K^2);
    
                else
                % Cut
                    b0 = (1+K/Q+K^2)/(1+K/(V0*Q)+K^2);
                    b1 = 2*(K^2-1)/(1+K/(V0*Q)+K^2);
                    b2 = (1-K/Q+K^2)/(1+K/(V0*Q)+K^2);
                    a1 = 2*(K^2-1)/(1+K/(V0*Q)+K^2);
                    a2 = (1-K/(V0*Q)+K^2)/(1+K/(V0*Q)+K^2);
   
                end
                a = [1, a1, a2];
                b = [b0, b1, b2];
            end
        end
        function [audioOut] = LoudnessWeighting(app, audioIn)
            switch(app.LoudnessSwitch.Value)
            case 'Off'
                % no levelling
                audioOut = audioIn;
            case 'On'
                % A weighting
                weightFilt = weightingFilter('A-weighting',app.SamplingFreq);
                audioOut = weightFilt(audioIn);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Gain_output_flag = 0;
            app.Audio_pause_time = 0;
            app.SamplingFreq = 44100;
            [~,app.h_omega] = freqz(1,1,2048);
            app.h_prod = ones(size(app.h_omega));
            app.h_low = ones(size(app.h_omega));
            app.h_mid = ones(size(app.h_omega));
            app.h_high = ones(size(app.h_omega));
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            [app.Audio_loadfile,app.Audio_loadpath] = uigetfile({'*.wav;*.ogg;*.oga;*.flac;*.au;*.aiff;*.aif;*.aifc;*.mp3;*.m4a;*.mp4','All Audio Files (*.wav,*.ogg,*.oga,*.flac,*.au,*.aiff,*.aif,*.aifc,*.mp3,*.m4a,*.mp4)';...
                '*.wav','WAVE File (*.wav)';'*.ogg;*.oga','OGG File (*.ogg,*.oga)';'*.flac','FLAC File (*.flac)';'*.au','AU File (*.au)';'*.aiff;*.aif',...
                 'AIFF File (*.aiff,*.aif)';'*.aifc','AIFC File (*.aifc)';'*.mp3','MP3 File (*.mp3)';...
                 '*.m4a;*.mp4','MPEG-4 AAC (*.m4a,*.mp4)'},'Load Audio');
             figure(app.UIFigure); %Workaround to stop the Plugin closing when opening a file
             if ~isequal(app.Audio_loadfile,0)
                [app.Audio_input,app.SamplingFreq] = audioread([app.Audio_loadpath,app.Audio_loadfile]);
                app.Audio_processed = app.Audio_input;
                app.LowKnob.Value = 0;
                app.MidKnob.Value = 0;
                app.HighKnob.Value = 0;
                app.LowValue.Value = 0;
                app.MidValue.Value = 0;
                app.HighValue.Value = 0;
                app.LoudnessSwitch.Value = 'Off';
                app.h_low = ones(size(app.h_omega));
                app.h_mid = ones(size(app.h_omega));
                app.h_high = ones(size(app.h_omega));
                % Loaded Audio File Name
                [~,app.Audio_loadfile_name,app.Audio_loadfile_ext] = fileparts([app.Audio_loadpath,app.Audio_loadfile]);
                app.AudioName.Text = [app.Audio_loadfile_name, app.Audio_loadfile_ext];
            end
        end

        % Value changing function: LowKnob
        function LowKnobValueChanging(app, event)
            lowChangingValue = event.Value;
            app.LowValue.Value = lowChangingValue;
            [b,a] = ShelvingFilter(app, 'Low', lowChangingValue);
            [app.h_low,app.h_omega] = freqz(b,a,2048);

        end

        % Value changing function: MidKnob
        function MidKnobValueChanging(app, event)
            midChangingValue = event.Value;
            app.MidValue.Value = midChangingValue;
            [b,a] = PeakingFilter(app, midChangingValue);
            [app.h_mid,app.h_omega] = freqz(b,a,2048);
        end

        % Value changing function: HighKnob
        function HighKnobValueChanging(app, event)
            highChangingValue = event.Value;
            app.HighValue.Value = highChangingValue;
            [b,a] = ShelvingFilter(app, 'High', highChangingValue);
            [app.h_high,app.h_omega] = freqz(b,a,2048);
        end

        % Value changed function: LowValue
        function LowValueValueChanged(app, event)
            lowValue = app.LowValue.Value;
            app.LowKnob.Value = lowValue;
            [b,a] = ShelvingFilter(app, 'Low', lowValue);
            [app.h_low,app.h_omega] = freqz(b,a,2048);
            
        end

        % Value changed function: MidValue
        function MidValueValueChanged(app, event)
            midValue = app.MidValue.Value;
            app.MidKnob.Value = midValue;
            [b,a] = PeakingFilter(app, midValue);
            [app.h_mid,app.h_omega] = freqz(b,a,2048);
        end

        % Value changed function: LoudnessSwitch
        function LoudnessSwitchValueChanged(app, event)
            loudValue = app.LoudnessSwitch.Value;
            app.LoudnessSwitch.Value = loudValue;
            
        end

        % Value changed function: HighValue
        function HighValueValueChanged(app, event)
            highValue = app.HighValue.Value;
            app.HighKnob.Value = highValue;
            [b,a] = ShelvingFilter(app, 'High', highValue);
            [app.h_high,app.h_omega] = freqz(b,a,2048);
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if isempty(app.Audio_input)
                uialert(app.UIFigure,'No Audio File to Play','File Not Found')
            else
                if strcmp(app.PlayAudioButton.Text,'Play Audio')
                    app.h_prod = app.h_low.*app.h_mid.*app.h_high';
                    h_eq = app.h_low.*app.h_mid.*app.h_high;
                    if h_eq(:,1) == ones(size(app.h_omega))
                                 b = 1;
                                 a = 1;
                             else
                                [b,a]=invfreqz(h_eq,app.h_omega,2,2);
                    end
                            app.Audio_processed = filter(b,a,app.Audio_input);
                            app.Audio_processed = LoudnessWeighting(app, app.Audio_processed);
                            app.Audio_play = app.Audio_processed./max(max(abs(app.Audio_processed)));
                            app.Audio_playback_object = audioplayer(app.Audio_play,app.SamplingFreq);
                            play(app.Audio_playback_object)


                            % Pause Button Appears
                            app.Gain_output_flag = 0;
                            app.PauseAudioButton.Visible = 'on';
                            app.PlayAudioButton.Visible = 'off';
                            N = length(app.Audio_processed);
                            n = N/app.SamplingFreq;
                            app.LowKnob.Enable = 'off';
                            app.MidKnob.Enable = 'off';
                            app.HighKnob.Enable = 'off';
                            app.LoudnessSwitch.Enable = 'off';
                            pause(n);
                            app.LowKnob.Enable = 'on';
                            app.MidKnob.Enable = 'on';
                            app.HighKnob.Enable = 'on';
                            app.LoudnessSwitch.Enable = 'on';
                            app.PauseAudioButton.Visible = 'off';
                            app.PlayAudioButton.Visible = 'on';
                 end
            end
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            if isempty(app.Audio_input)
                uialert(app.UIFigure,'No Audio File','File Not Found')
            elseif isempty(app.Audio_playback_object)
                uialert(app.UIFigure,'No Sound Playing','File Not Found')
            else
                stop(app.Audio_playback_object)
                app.Gain_output_flag = 1;
                app.PauseAudioButton.Visible = 'off';
                app.PlayAudioButton.Visible = 'on';
                app.LowKnob.Enable = 'on';
                app.MidKnob.Enable = 'on';
                app.HighKnob.Enable = 'on';
                app.LoudnessSwitch.Enable = 'on';
                app.PlayAudioButton.Text = 'Play Audio';
                app.Audio_pause_time = 0;
            end
        end

        % Button pushed function: PauseAudioButton
        function PauseAudioButtonPushed(app, event)
            pause(app.Audio_playback_object)
            app.Gain_output_flag = 2;
            app.LowKnob.Enable = 'on';
            app.MidKnob.Enable = 'on';
            app.HighKnob.Enable = 'on';
            app.LoudnessSwitch.Enable = 'on';
            app.PauseAudioButton.Visible = 'off';
            app.PlayAudioButton.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LowKnobLabel = uilabel(app.UIFigure);
            app.LowKnobLabel.Text = 'Low';
            app.LowKnob = uiknob(app.UIFigure, 'continuous');
            app.LowKnob.Limits = [-30 30];
            app.LowKnob.MajorTicks = [-30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30];
            app.LowKnob.MajorTickLabels = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.LowKnob.ValueChangingFcn = createCallbackFcn(app, @LowKnobValueChanging, true);
            app.LowKnob.MinorTicks = [-30 -29.8575 -29.715 -29.5725 -29.43 -29.2875 -29.145 -29.0025 -28.86 -28.7175 -28.575 -28.4325 -28.29 -28.1475 -28.005 -27.8625 -27.72 -27.5775 -27.435 -27.2925 -27.15 -27.0075 -26.865 -26.7225 -26.58 -26.4375 -26.295 -26.1525 -26.01 -25.8675];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
            app.LoadAudioButton = uibutton(app.UIFigure, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.PauseAudioButton = uibutton(app.UIFigure, 'push');
            app.PauseAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PauseAudioButtonPushed, true);
            app.PauseAudioButton.Visible = 'off';
            app.PauseAudioButton.Text = 'Pause Audio';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.UIFigure, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.AudioName = uilabel(app.UIFigure);
            app.AudioName.Text = '';
            app.MidKnobLabel = uilabel(app.UIFigure);
            app.MidKnobLabel.Text = 'Mid';
            app.MidKnob = uiknob(app.UIFigure, 'continuous');
            app.MidKnob.Limits = [-30 30];
            app.MidKnob.MajorTicks = [-30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30];
            app.MidKnob.MajorTickLabels = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.MidKnob.ValueChangingFcn = createCallbackFcn(app, @MidKnobValueChanging, true);
            app.MidKnob.MinorTicks = [-30 -29.8575 -29.715 -29.5725 -29.43 -29.2875 -29.145 -29.0025 -28.86 -28.7175 -28.575 -28.4325 -28.29 -28.1475 -28.005 -27.8625 -27.72 -27.5775 -27.435 -27.2925 -27.15 -27.0075 -26.865 -26.7225 -26.58 -26.4375 -26.295 -26.1525 -26.01 -25.8675];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
            app.HighKnobLabel = uilabel(app.UIFigure);
            app.HighKnobLabel.Text = 'High';
            app.HighKnob = uiknob(app.UIFigure, 'continuous');
            app.HighKnob.Limits = [-30 30];
            app.HighKnob.MajorTicks = [-30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30];
            app.HighKnob.MajorTickLabels = {'-30', '-25', '-20', '-15', '-10', '-5', '0', '5', '10', '15', '20', '25', '30'};
            app.HighKnob.ValueChangingFcn = createCallbackFcn(app, @HighKnobValueChanging, true);
            app.HighKnob.MinorTicks = [-30 -29.8575 -29.715 -29.5725 -29.43 -29.2875 -29.145 -29.0025 -28.86 -28.7175 -28.575 -28.4325 -28.29 -28.1475 -28.005 -27.8625 -27.72 -27.5775 -27.435 -27.2925 -27.15 -27.0075 -26.865 -26.7225 -26.58 -26.4375 -26.295 -26.1525 -26.01 -25.8675];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
            app.LowValue = uieditfield(app.UIFigure, 'numeric');
            app.LowValue.Limits = [-30 30];
            app.LowValue.ValueChangedFcn = createCallbackFcn(app, @LowValueValueChanged, true);
            app.MidValue = uieditfield(app.UIFigure, 'numeric');
            app.MidValue.Limits = [-30 30];
            app.MidValue.ValueChangedFcn = createCallbackFcn(app, @MidValueValueChanged, true);
            app.HighValue = uieditfield(app.UIFigure, 'numeric');
            app.HighValue.Limits = [-30 30];
            app.HighValue.ValueChangedFcn = createCallbackFcn(app, @HighValueValueChanged, true);
            app.ThreeBandFilterLabel = uilabel(app.UIFigure);
            app.ThreeBandFilterLabel.Text = 'Three Band Filter';
            app.Playing________Label = uilabel(app.UIFigure);
            app.Playing________Label.Text = '________Playing________';
            app.LoudnessSwitchLabel = uilabel(app.UIFigure);
            app.LoudnessSwitchLabel.Text = 'Loudness';
            app.LoudnessSwitch = uiswitch(app.UIFigure, 'rocker');
            app.LoudnessSwitch.ValueChangedFcn = createCallbackFcn(app, @LoudnessSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeBandFilterApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end